classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        SimulationSettingsPanel  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        InitialTimeLabel_5       matlab.ui.control.Label
        InitialTimeLabel_4       matlab.ui.control.Label
        step_edit                matlab.ui.control.NumericEditField
        InitialTimeLabel_3       matlab.ui.control.Label
        finalTime_edit           matlab.ui.control.NumericEditField
        initTime_edit            matlab.ui.control.NumericEditField
        text17_3                 matlab.ui.control.Label
        text17_2                 matlab.ui.control.Label
        text17                   matlab.ui.control.Label
        ControllerPanel          matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        controller_button        matlab.ui.control.Button
        controller_popupmenu     matlab.ui.control.DropDown
        ModelSettingsPanel       matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        cubesat_button           matlab.ui.control.Button
        perturbation_button      matlab.ui.control.Button
        logo_2                   matlab.ui.control.Image
        logo                     matlab.ui.control.Image
        run_button               matlab.ui.control.Button
        text20                   matlab.ui.control.Label
        uibuttongroup7           matlab.ui.container.ButtonGroup
        GridLayout6              matlab.ui.container.GridLayout
        compCost_text            matlab.ui.control.Label
        ascct_text               matlab.ui.control.Label
        eulerint_text            matlab.ui.control.Label
        settime_text             matlab.ui.control.Label
        text11                   matlab.ui.control.Label
        text10                   matlab.ui.control.Label
        text9                    matlab.ui.control.Label
        text8                    matlab.ui.control.Label
        uibuttongroup1           matlab.ui.container.ButtonGroup
        GridLayout4              matlab.ui.container.GridLayout
        graphic2_popupmenu       matlab.ui.control.DropDown
        text3                    matlab.ui.control.Label
        graphic2_button          matlab.ui.control.Button
        graphic2_axes            matlab.ui.control.UIAxes
        uipanel1                 matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        text2                    matlab.ui.control.Label
        graphic1_popupmenu       matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        text16                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        callSatParameters % Description
        callDisturbances
        simulation % Description
    end
    
    properties (Access = public)
       simulationParameters
    end
    
    methods (Access = private)
        function parameters = initializeSimulationParameters(~)
            %CubeSat Parameters
            parameters.initialValues.I = eye(3);
            parameters.initialValues.q0 =[1,0,0,0]';
            parameters.initialValues.Wo =[0,0,0]';
            %Controller Parameters
            parameters.setPoint.qd =[0.9808,0.1691,0.0933,0.0277]';
            parameters.setPoint.Wd =[0,0,0]'; %rad/s
            %Feedback Controller parameters
            parameters.feedback.Keye = 2*eye(3);
            parameters.feedback.Peye = 3*eye(3);
            %Boskovic Controller parameters
            parameters.boskController.delta = 0.01;
            parameters.boskController.gamma = 0.001;
            parameters.boskController.Umax  = 0.10;
            parameters.boskController.k0    = 1;
            %Disturbances torque parameters
            parameters.disturbances.constant.Tdx = 0.001;
            parameters.disturbances.constant.Tdy = 0.001;
            parameters.disturbances.constant.Tdz = 0.001;
            parameters.disturbances.constant.enable = 1;   % 1-off, 2-on
            parameters.disturbances.variable.A   = 0.01;
            parameters.disturbances.variable.w1  = 1.00;
            parameters.disturbances.variable.w2  = 2.00;
            parameters.disturbances.variable.w3  = 3.00;
            parameters.disturbances.variable.enable = 1;   % 1-off, 2-on
        end
        
        function validateData(~, time)
            if isnan(time.ti)
                errordlg('Invalid value of Initial Time.', 'Invalid Value', 'modal');
            elseif isnan(time.tf)
                errordlg('Invalid value of Final Time.', 'Invalid Value', 'modal');
            elseif isnan(time.step)
                errordlg('Invalid value of Step.', 'Invalid Value', 'modal');
            elseif time.ti >= time.tf
                errordlg('Initial Time must be lower than Final Time.', 'Invalid Value', 'modal');
            elseif time.step > (time.tf-time.ti)
                errordlg('Invalid value of step.', 'Invalid Value', 'modal');
            end
        end
        
        function td_arr = getDisturbances(~, disturbances, time)
            %%%Recover time
            t = time.t;
            n = time.n;
            %%Create disturbances array
            Tdx = disturbances.constant.Tdx;
            Tdy = disturbances.constant.Tdy;
            Tdz = disturbances.constant.Tdz;
            Td_const_arr = repmat([Tdx,Tdy,Tdz],n,1)';
            A  = disturbances.variable.A;
            w1 = disturbances.variable.w1;
            w2 = disturbances.variable.w2;
            w3 = disturbances.variable.w3;
            td_misc_arr = A*sin([w1*t;w2*t;w3*t]);
            if     (disturbances.constant.enable == 2) && (disturbances.variable.enable == 2)
                td_arr = Td_const_arr+td_misc_arr;
            elseif (disturbances.constant.enable == 2) && (disturbances.variable.enable == 1)
                td_arr = Td_const_arr;
            elseif (disturbances.constant.enable == 1) && (disturbances.variable.enable == 2)
                td_arr = td_misc_arr;
            else
                td_arr = zeros(3,n);
            end
        end
    end
    
    methods (Access = public)
        
        function importData(app, receivedData)
           switch  receivedData.i
               case 1
                    app.simulationParameters.initialValues.I  = receivedData.I;
                    app.simulationParameters.initialValues.q0 = receivedData.q0';
                    app.simulationParameters.initialValues.Wo = receivedData.Wo';  
                    msgbox("Initial conditions successfully modified","Success");
               case 2
                    app.simulationParameters.disturbances.constant.Tdx = receivedData.Tdx;
                    app.simulationParameters.disturbances.constant.Tdy = receivedData.Tdy;
                    app.simulationParameters.disturbances.constant.Tdz = receivedData.Tdz;
                    app.simulationParameters.disturbances.constant.enable = receivedData.constantEnable;

                    app.simulationParameters.disturbances.variable.A   = receivedData.A;
                    app.simulationParameters.disturbances.variable.w1  = receivedData.w1;
                    app.simulationParameters.disturbances.variable.w2  = receivedData.w2;
                    app.simulationParameters.disturbances.variable.w3  = receivedData.w3;
                    app.simulationParameters.disturbances.variable.enable = receivedData.miscEnable;

                    msgbox("Disturbances parameter modified","Success");
               case 4
                    app.simulationParameters.setPoint.qd = receivedData.qd';
                    %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
                    app.simulationParameters.feedback.Keye = receivedData.K_eye;
                    app.simulationParameters.feedback.Peye = receivedData.P_eye;
                    msgbox("Controller parameters modified","Success");
               case 5
                    app.simulationParameters.setPoint.qd = receivedData.qd';
                    %app.simulationParameters.setPoint.Wd = receivedData.wd; %rad/s
                    app.simulationParameters.boskController.delta = receivedData.delta;
                    app.simulationParameters.boskController.gamma = receivedData.gamma;
                    app.simulationParameters.boskController.Umax  = receivedData.Umax;
                    app.simulationParameters.boskController.k0    = receivedData.k0;
                    msgbox("Controller parameters modified","Success");
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulationParameters = initializeSimulationParameters(app);
        end

        % Button pushed function: cubesat_button
        function cubesat_buttonButtonPushed(app, event)
            app.callSatParameters = cubesatSettings(app,app.simulationParameters.initialValues);
        end

        % Button pushed function: perturbation_button
        function perturbation_buttonButtonPushed(app, event)
            app.callDisturbances = perturbationSettings(app,app.simulationParameters.disturbances);
        end

        % Button pushed function: controller_button
        function controller_buttonButtonPushed(app, event)
            v = app.controller_popupmenu.ValueIndex;
            switch v
                case 1
                    feedbackSettingd(app,app.simulationParameters.setPoint,app.simulationParameters.feedback);
                case 2
                    boskovicSettings(app,app.simulationParameters.setPoint,app.simulationParameters.boskController);
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            %%%Read time simulation Parameters
            time.ti   = app.initTime_edit.Value;
            time.tf   = app.finalTime_edit.Value;
            time.step = app.step_edit.Value;
            %%%Validate data 
            app.validateData(time);
            %%%Create time vector
            time.t=time.ti:time.step:time.tf;
            time.n=length(time.t);

            %%%Create disturbances array
            sim.td = app.getDisturbances(app.simulationParameters.disturbances, time);
            
            %%%RunSimulation
            v = app.controller_popupmenu.ValueIndex;
            switch v
            case 1
                [sim.x, sim.u, sim.eulerInt, sim.ascct, sim.o, sim.ts] = feedback_rk4(app,sim.td, app.simulationParameters, time);
              

                %%plot(app.UIAxes,time.t,sim.x(1:4,:));
            case 2
            %    [kArray,angArray,xArray,uiArray,TdArray,EULERINT,ASCCT,T,ts] = ...
            %        Boskovic_simulation(td_arr,gamma,delta,k0,n,td_arr,handles.Umax,handles.I,qd_Array,...
            %         wd_Array,ui,x);
            %         handles.c1=2;
            %         %handles.graphic1_popupmenu.String = 'K';
            otherwise
                disp('other value')
            end
            
            %%%Reset selector
            app.graphic1_popupmenu.Enable = "on";
            app.graphic1_popupmenu.ValueIndex = 1;
            %%%Plot euler angles by default
            plot(app.UIAxes,time.t, 180/pi*quat2eul(sim.x(1:4,:)')');
            xlabel(app.UIAxes,'Time(s)'); ylabel(app.UIAxes,'Euler Angles(deg)');
            legend(app.UIAxes,'Roll','Pitch','Yaw');
            %%%turn into global
            app.simulation.results = sim;
            app.simulation.time = time;

            %LS2125204: Update Controller performance parameters
            app.settime_text.Text   = num2str(sim.ts,'%.2E');
            app.eulerint_text.Text  = num2str(sim.eulerInt(end-1),'%.2E');
            app.ascct_text.Text     = num2str(sim.ascct(end-1),'%.2E');
            app.compCost_text.Text  = num2str(mean(sim.o(2:end)),'%.2E');
        end

        % Value changed function: graphic1_popupmenu
        function graphic1_popupmenuValueChanged(app, event)
            value = app.graphic1_popupmenu.ValueIndex;
            switch value
                case 1
                    plot(app.UIAxes,app.simulation.time.t, 180/pi*quat2eul(app.simulation.results.x(1:4,:)')');
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'Euler Angles(deg)');
                    legend(app.UIAxes,'Roll','Pitch','Yaw');
                case 2
                    plot(app.UIAxes,app.simulation.time.t,app.simulation.results.x(1:4,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'q0','q1','q2','q3');
                    ylabel(app.UIAxes,'Quaternions');
                case 3
                    plot(app.UIAxes, app.simulation.time.t,app.simulation.results.x(5:7,:))
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Angular Rates Error(rad/s)');
                case 4
                    plot(app.UIAxes, app.simulation.time.t,app.simulation.results.td)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Disturbances Torques(Nm)');
                case 5
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.u)
                    xlabel(app.UIAxes,'Time(s)');
                    legend(app.UIAxes,'X','Y','Z');
                    ylabel(app.UIAxes,'Control Torque(Nm)');
                case 6
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.eulerInt)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'EULERINT (rad.s)');
                    legend(app.UIAxes,'eulerint');
                case 7
                    plot(app.UIAxes, app.simulation.time.t, app.simulation.results.ascct)
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'ASCCT (Nm)');
                    legend(app.UIAxes,'ascct');
               case 8
                    plot(app.UIAxes, app.simulation.time.t(2:end), app.simulation.results.o(2:end))
                    xlabel(app.UIAxes,'Time(s)');
                    ylabel(app.UIAxes,'Computational cost <o> (s)');
                    legend(app.UIAxes,'o');
                % case 8
                %     if handles.c1 == 2
                %         axes(handles.graphic1_axes);
                %         plot(handles.t(1:handles.n-1),handles.kArray)
                %         grid on; xlabel('Time(s)');
                %         ylabel('Adaptive gain K');
                %     else
                %         warndlg('Feedback Controller does not have K value.','K value', 'modal');
                %     end
                otherwise
                    disp('other value')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.text16 = uilabel(app.GridLayout);
            app.text16.Tag = 'text16';
            app.text16.Text = 'CubeSat Control Attitude Evaluation';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Graph 1';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Simulation Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.graphic1_popupmenu = uidropdown(app.GridLayout7);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'O'};
            app.graphic1_popupmenu.ValueChangedFcn = createCallbackFcn(app, @graphic1_popupmenuValueChanged, true);
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Enable = 'off';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.GridLayout7);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.uibuttongroup1 = uibuttongroup(app.GridLayout);
            app.uibuttongroup1.Title = 'Graph 2';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.GridLayout4 = uigridlayout(app.uibuttongroup1);
            app.graphic2_axes = uiaxes(app.GridLayout4);
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_button.Tag = 'graphic2_button';
            app.graphic2_button.Enable = 'off';
            app.graphic2_button.Text = 'Graph';
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Parameter:';
            app.graphic2_popupmenu = uidropdown(app.GridLayout4);
            app.graphic2_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'K'};
            app.graphic2_popupmenu.Tag = 'graphic2_popupmenu';
            app.graphic2_popupmenu.Value = 'Euler Angles (ZYX)';
            app.uibuttongroup7 = uibuttongroup(app.GridLayout);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.GridLayout6 = uigridlayout(app.uibuttongroup7);
            app.text8 = uilabel(app.GridLayout6);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.GridLayout6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.GridLayout6);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.GridLayout6);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.GridLayout6);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.GridLayout6);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.text20 = uilabel(app.GridLayout);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Convenio/Contrato N° PE501079885-2022-PROCIENCIA';
            app.run_button = uibutton(app.GridLayout, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run';
            app.logo = uiimage(app.GridLayout);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            app.logo_2 = uiimage(app.GridLayout);
            app.logo_2.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            app.ModelSettingsPanel = uipanel(app.GridLayout);
            app.ModelSettingsPanel.Title = 'Model Settings';
            app.GridLayout2 = uigridlayout(app.ModelSettingsPanel);
            app.perturbation_button = uibutton(app.GridLayout2, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_buttonButtonPushed, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.cubesat_button = uibutton(app.GridLayout2, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_buttonButtonPushed, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.ControllerPanel = uipanel(app.GridLayout);
            app.ControllerPanel.Title = 'Controller';
            app.GridLayout3 = uigridlayout(app.ControllerPanel);
            app.controller_popupmenu = uidropdown(app.GridLayout3);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.GridLayout3, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_buttonButtonPushed, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.SimulationSettingsPanel = uipanel(app.GridLayout);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout5 = uigridlayout(app.SimulationSettingsPanel);
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text17_2 = uilabel(app.GridLayout5);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 's';
            app.text17_3 = uilabel(app.GridLayout5);
            app.text17_3.Tag = 'text17';
            app.text17_3.Text = 's';
            app.initTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.initTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.finalTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit.Value = 100;
            app.InitialTimeLabel_3 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_3.Text = 'Step:';
            app.step_edit = uieditfield(app.GridLayout5, 'numeric');
            app.step_edit.ValueDisplayFormat = '%.E';
            app.step_edit.Value = 0.01;
            app.InitialTimeLabel_4 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_4.Text = 'Final time:';
            app.InitialTimeLabel_5 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_5.Text = 'Initial time:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end