classdef ControllerSettings_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        Image             matlab.ui.control.Image
        Applu_pushbutton  matlab.ui.control.Button
        uipanel2          matlab.ui.container.Panel
        text13            matlab.ui.control.Label
        text12            matlab.ui.control.Label
        text11            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        P_edit            matlab.ui.control.EditField
        K_edit            matlab.ui.control.EditField
        P                 matlab.ui.control.Label
        K                 matlab.ui.control.Label
        uipanel1          matlab.ui.container.Panel
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        wz_edit           matlab.ui.control.EditField
        wy_edit           matlab.ui.control.EditField
        wx_edit           matlab.ui.control.EditField
        yaw_edit          matlab.ui.control.EditField
        pitch_edit        matlab.ui.control.EditField
        roll_edit         matlab.ui.control.EditField
        text2             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ControllerSettings_OpeningFcn(app, varargin)
            % --- Executes just before ControllerSettings is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ControllerSettings (see VARARGIN)
            
            % Choose default command line output for ControllerSettings
            handles.output = hObject;
            if (isempty(varargin) == 0)
                %Desired Euler angles
                euler = quat2eul(varargin{1,1}')*180/pi;
                set(handles.roll_edit, 'String', num2str(euler(1),'%.2f'));
                set(handles.pitch_edit, 'String', num2str(euler(2),'%.2f'));
                set(handles.yaw_edit, 'String', num2str(euler(3),'%.2f'));
                %Controller parameters
                set(handles.K_edit, 'String', num2str(varargin{1,3}(1,1),'%.2f'));
                set(handles.P_edit, 'String', num2str(varargin{1,4}(1,1),'%.2f'));
            end
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Applu_pushbutton
        function Applu_pushbutton_Callback(app, event)
            % --- Executes on button press in Applu_pushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Applu_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % LS2125204 Read Set point
            Roll = str2double(get(handles.roll_edit, 'string'));
            Pitch = str2double(get(handles.pitch_edit, 'string'));
            Yaw = str2double(get(handles.yaw_edit, 'string'));
            Wx = str2double(get(handles.wx_edit, 'string'));
            Wy = str2double(get(handles.wy_edit, 'string'));
            Wz = str2double(get(handles.wz_edit, 'string'));
            K = str2double(get(handles.K_edit, 'string'));
            P = str2double(get(handles.P_edit, 'string'));
            if isnan(Roll)
                errordlg('Invalid value of Roll.', 'Invalid Value', 'modal');
            elseif isnan(Pitch)
                errordlg('Invalid value of Pitch.', 'Invalid Value', 'modal');
            elseif isnan(Yaw)
                errordlg('Invalid value of Yaw.', 'Invalid Value', 'modal');
            elseif isnan(Wx)
                errordlg('Invalid value of Wx.', 'Invalid Value', 'modal');
            elseif isnan(Wy)
                errordlg('Invalid value of Wy.', 'Invalid Value', 'modal');
            elseif isnan(Wz)
                errordlg('Invalid value of Wz.', 'Invalid Value', 'modal');
            elseif isnan(K)
                errordlg('Invalid value of K.', 'Invalid Value', 'modal');
            elseif isnan(P)
                errordlg('Invalid value of P.', 'Invalid Value', 'modal');
            else
                handles.qd=eul2quat(pi/180*[Roll,Pitch,Yaw])';
                handles.Wd=pi/180*[Wx,Wy,Wz]';  %rad/s
                handles.Keye=K*eye(3,3);
                handles.Peye=P*eye(3,3);
                LS2125204(2,handles.qd,handles.Wd,handles.Keye,handles.Peye);
            end
            
            %Update handles structure
            guidata(hObject, handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ControllerSettings';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'FeedBack Controller Settings';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Set Point';
            app.uipanel1.Tag = 'uipanel1';
            app.roll_edit = uieditfield(app.uipanel1, 'text');
            app.roll_edit.Tag = 'roll_edit';
            app.roll_edit.Value = '5.00';
            app.pitch_edit = uieditfield(app.uipanel1, 'text');
            app.pitch_edit.Tag = 'pitch_edit';
            app.pitch_edit.Value = '10.00';
            app.yaw_edit = uieditfield(app.uipanel1, 'text');
            app.yaw_edit.Tag = 'yaw_edit';
            app.yaw_edit.Value = '15.00';
            app.wx_edit = uieditfield(app.uipanel1, 'text');
            app.wx_edit.Tag = 'wx_edit';
            app.wx_edit.Enable = 'off';
            app.wx_edit.Value = '0.00';
            app.wy_edit = uieditfield(app.uipanel1, 'text');
            app.wy_edit.Tag = 'wy_edit';
            app.wy_edit.Enable = 'off';
            app.wy_edit.Value = '0.00';
            app.wz_edit = uieditfield(app.uipanel1, 'text');
            app.wz_edit.Tag = 'wz_edit';
            app.wz_edit.Enable = 'off';
            app.wz_edit.Value = '0.00';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Y';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Z';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Euler Angles (deg):';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Angular Rate (deg/s):';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Gains';
            app.uipanel2.Tag = 'uipanel2';
            app.K = uilabel(app.uipanel2);
            app.K.Tag = 'K';
            app.K.Text = 'K:';
            app.P = uilabel(app.uipanel2);
            app.P.Tag = 'P';
            app.P.Text = 'P:';
            app.K_edit = uieditfield(app.uipanel2, 'text');
            app.K_edit.Tag = 'K_edit';
            app.K_edit.Value = '1.00';
            app.P_edit = uieditfield(app.uipanel2, 'text');
            app.P_edit.Tag = 'P_edit';
            app.P_edit.Value = '1.00';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'DIAG(';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'DIAG(';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = ')';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = ')';
            app.Applu_pushbutton = uibutton(app.figure1, 'push');
            app.Applu_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @Applu_pushbutton_Callback, true);
            app.Applu_pushbutton.Tag = 'Applu_pushbutton';
            app.Applu_pushbutton.Text = 'Apply';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControllerSettings_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ControllerSettings_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end