classdef LS2125204_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uitoolbar1            matlab.ui.container.Toolbar
        logo                  matlab.ui.control.Image
        run_button            matlab.ui.control.Button
        text20                matlab.ui.control.Label
        uibuttongroup7        matlab.ui.container.ButtonGroup
        compCost_text         matlab.ui.control.Label
        ascct_text            matlab.ui.control.Label
        eulerint_text         matlab.ui.control.Label
        settime_text          matlab.ui.control.Label
        text11                matlab.ui.control.Label
        text10                matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        uibuttongroup6        matlab.ui.container.ButtonGroup
        controller_button     matlab.ui.control.Button
        controller_popupmenu  matlab.ui.control.DropDown
        uibuttongroup5        matlab.ui.container.ButtonGroup
        perturbation_button   matlab.ui.control.Button
        cubesat_button        matlab.ui.control.Button
        uibuttongroup2        matlab.ui.container.ButtonGroup
        text19                matlab.ui.control.Label
        text18                matlab.ui.control.Label
        text17                matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        step_edit             matlab.ui.control.EditField
        finalTime_edit        matlab.ui.control.EditField
        initTime_edit         matlab.ui.control.EditField
        uibuttongroup1        matlab.ui.container.ButtonGroup
        graphic2_popupmenu    matlab.ui.control.DropDown
        text3                 matlab.ui.control.Label
        graphic2_button       matlab.ui.control.Button
        graphic2_axes         matlab.ui.control.UIAxes
        uipanel1              matlab.ui.container.Panel
        graphic1_button       matlab.ui.control.Button
        text2                 matlab.ui.control.Label
        graphic1_popupmenu    matlab.ui.control.DropDown
        graphic1_axes         matlab.ui.control.UIAxes
        text16                matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp            % Description
        simulationParameters  % object that will store sim parameters
    end
    
    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool2, app.uitoggletool1, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LS2125204_OpeningFcn(app, varargin)
            % --- Executes just before LS2125204 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            
            if (isempty(varargin) == 0)
                n = varargin{1};
                switch n
                case 1
                    app.simulationParameters.I  = varargin{2};
                    app.simulationParameters.q0 = varargin{3};
                    app.simulationParameters.Wo = varargin{4};
                case 2
                    app.simulationParameters.qd   = varargin{2};
                    app.simulationParameters.Wd   = varargin{3}; %rad/s
                    app.simulationParameters.Keye = varargin{4};
                    app.simulationParameters.Peye = varargin{5};
                case 3
                    app.simulationParameters.Tdx = varargin{2};
                    app.simulationParameters.Tdy = varargin{3};
                    app.simulationParameters.Tdz = varargin{4};
                    app.simulationParameters.i=1;
                case 4
                    app.simulationParameters.A = varargin{2};
                    app.simulationParameters.w1 = varargin{3};
                    app.simulationParameters.w2 = varargin{4};
                    app.simulationParameters.w3 = varargin{5};
                    app.simulationParameters.i=2;
                case 5
                    app.simulationParameters.qd   = varargin{2};
                    app.simulationParameters.Wd   = varargin{3};
                    app.simulationParameters.delta = varargin{4};
                    app.simulationParameters.gamma = varargin{5};
                    app.simulationParameters.Umax  = varargin{6};
                    app.simulationParameters.k0    = varargin{7};
               otherwise
                    disp('other value')
                end
            else
                % Initial default values
                %CubeSat Parameters
                app.simulationParameters.I =2*eye(3);
                app.simulationParameters.q0 =[1,0,0,0]';
                app.simulationParameters.Wo =[0,0,0]';
                %Controller Parameters
                app.simulationParameters.qd =[0.9808,0.1691,0.0933,0.0277]';
                app.simulationParameters.Wd =[0,0,0]'; %rad/s
                %Feedback Controller parameters
                app.simulationParameters.Keye = eye(3);
                app.simulationParameters.Peye = eye(3);
                %Boskovic Controller parameters
                app.simulationParameters.delta = 0.01;
                app.simulationParameters.gamma = 0.001;
                app.simulationParameters.Umax  = 0.10;
                app.simulationParameters.k0    = 1;
                %Disturbances torque parameters
                app.simulationParameters.Tdx = 0.001;
                app.simulationParameters.Tdy = 0.001;
                app.simulationParameters.Tdz = 0.001;
                app.simulationParameters.A = 0.01;
                app.simulationParameters.w1 = 1.00;
                app.simulationParameters.w2 = 2.00;
                app.simulationParameters.w3 = 3.00;
                app.simulationParameters.i=0;
            end

        end

        % Callback function: controller_button, logo
        function controller_button_Callback(app, event)
            % --- Executes on button press in controller_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to controller_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            v = get(handles.controller_popupmenu, 'value');
            switch v
                case 1
                    ControllerSettings(handles.qd, handles.Wd,handles.Keye,handles.Peye);
                case 2
                    BoskSettings(handles.qd, handles.Wd, handles.delta, handles.gamma,...
                        handles.Umax, handles.k0);
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: cubesat_button
        function cubesat_button_Callback(app, event)
            % --- Executes on button press in cubesat_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.callingapp = cubesatSettings(app.simulationParameters);

            % hObject    handle to cubesat_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %CubeSarSettings(handles.I,handles.q0,handles.Wo);
        end

        % Button pushed function: graphic1_button
        function graphic1_button_Callback(app, event)
            % --- Executes on button press in graphic1_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to graphic1_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            v = get(handles.graphic1_popupmenu, 'value');
            switch v
                case 1
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),180/pi*handles.angArray)
                    grid on; xlabel('Time(s)');
                    ylabel('Euler Angles(deg)');
                    legend('Roll','Pitch','Yaw');
                case 2
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),handles.xArray(1:4,:))
                    grid on; xlabel('Time(s)');
                    legend('q0','q1','q2','q3');
                    ylabel('Quaternions');
                case 3
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),handles.xArray(5:7,:))
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Angular Rates Error(rad/s)');
                case 4
                    axes(handles.graphic1_axes);
                    plot(handles.t,handles.Td)
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Disturbances Torques(Nm)');
                case 5
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),handles.uiArray)
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Control Torque(Nm)');
                case 6
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),handles.EULERINT)
                    grid on; xlabel('Time(s)');
                    ylabel('EULERINT (rad.s)');
                case 7
                    axes(handles.graphic1_axes);
                    plot(handles.t(1:handles.n-1),handles.ASCCT)
                    grid on; xlabel('Time(s)');
                    ylabel('ASCCT (Nm)');
                case 8
                    if handles.c1 == 2
                        axes(handles.graphic1_axes);
                        plot(handles.t(1:handles.n-1),handles.kArray)
                        grid on; xlabel('Time(s)');
                        ylabel('Adaptive gain K');
                    else
                        warndlg('Feedback Controller does not have K value.','K value', 'modal');
                    end
                otherwise
                    disp('other value')
            end
            %Update handles structure
            guidata(hObject, handles)
        end

        % Button pushed function: graphic2_button
        function graphic2_button_Callback(app, event)
            % --- Executes on button press in graphic2_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to graphic2_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            v = get(handles.graphic2_popupmenu, 'value');
            switch v
                case 1
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),180/pi*handles.angArray)
                    grid on; xlabel('Time(s)');
                    ylabel('Euler Angles(deg)');
                    legend('Roll','Pitch','Yaw');
                case 2
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),handles.xArray(1:4,:))
                    grid on; xlabel('Time(s)');
                    legend('q0','q1','q2','q3');
                    ylabel('Quaternions');
                case 3
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),handles.xArray(5:7,:))
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Angular Rates Error(rad/s)');
                case 4
                    axes(handles.graphic2_axes);
                    plot(handles.t,handles.Td)
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Disturbances Torques(Nm)');
                case 5
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),handles.uiArray)
                    grid on; xlabel('Time(s)');
                    legend('X','Y','Z');
                    ylabel('Control Torque(Nm)');
                case 6
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),handles.EULERINT)
                    grid on; xlabel('Time(s)');
                    ylabel('EULERINT (rad.s)');
                case 7
                    axes(handles.graphic2_axes);
                    plot(handles.t(1:handles.n-1),handles.ASCCT)
                    grid on; xlabel('Time(s)');
                    ylabel('ASCCT (Nm)');
                case 8
                    if handles.c1 == 2
                        axes(handles.graphic2_axes);
                        plot(handles.t(1:handles.n-1),handles.kArray)
                        grid on; xlabel('Time(s)');
                        ylabel('Adaptive gain K');
                    else
                        warndlg('Feedback Controller does not have K value.','K value', 'modal');
                    end
                otherwise
                    disp('other value')
            end
            %Update handles structure
            guidata(hObject, handles)
        end

        % Button pushed function: perturbation_button
        function perturbation_button_Callback(app, event)
            % --- Executes on button press in perturbation_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to perturbation_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PerturbationSettings(handles.Tdx,handles.Tdy,handles.Tdz,handles.A,...
                    handles.w1,handles.w2,handles.w3);
        end

        % Button pushed function: run_button
        function run_button_Callback(app, event)
            % --- Executes on button press in run_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % LS2125204: Get simulation parameters
            ti = str2double(get(handles.initTime_edit, 'string'));
            tf = str2double(get(handles.finalTime_edit, 'string'));
            step = str2double(get(handles.step_edit, 'string'));
            if isnan(ti)
                errordlg('Invalid value of Initial Time.', 'Invalid Value', 'modal');
            elseif isnan(tf)
                errordlg('Invalid value of Final Time.', 'Invalid Value', 'modal');
            elseif isnan(step)
                errordlg('Invalid value of Step.', 'Invalid Value', 'modal');
            elseif ti >= tf
                errordlg('Initial Time must be lower than Final Time.', 'Invalid Value', 'modal');
            else
                % LS2125204: Create time vector
                handles.t=ti:step:tf;
                handles.n=length(handles.t);
                % LS2125204: Create set-point array
                % Desired attitude array
                qd_Array=[handles.qd(1)*(ones(handles.n,1)),handles.qd(2)*...
                    (ones(handles.n,1)),handles.qd(3)*(ones(handles.n,1)),...
                    handles.qd(4)*(ones(handles.n,1))]';
                %Desired angular rate array
                wd_Array=[handles.Wd(1)*(ones(handles.n,1)),handles.Wd(2)*...
                    (ones(handles.n,1)), handles.Wd(3)*(ones(handles.n,1))]';
                %Initial control torque (Nm)
                ui=zeros(3,1);
                if (handles.i==0)
                    handles.Td=zeros(3,handles.n);
                elseif (handles.i==1)
                    handles.Td=[handles.Tdx*(ones(handles.n,1)),handles.Tdy*...
                        (ones(handles.n,1)),handles.Tdz*(ones(handles.n,1))]';
                elseif (handles.i==2)
                    handles.Td=handles.A*[sin(handles.w1*handles.t);sin(handles.w2...
                        *handles.t);sin(handles.w3*handles.t)];
                end
                %State Space Vector
                x=[handles.q0;handles.Wo];
            
                v = get(handles.controller_popupmenu, 'value');
                switch v
                    case 1
                    %Perform Simulation
                    [handles.angArray,handles.xArray,handles.uiArray,handles.TdArray,...
                    handles.EULERINT,handles.ASCCT,T,ts] = Feedback_simulation(handles.Td,...
                    handles.Peye,handles.Keye,handles.n,handles.t,...
                    handles.I,qd_Array,wd_Array,ui,x);
                    handles.kArray=[];
                    handles.c1=1;
                    case 2
                    [handles.kArray,handles.angArray,handles.xArray,handles.uiArray,...
                    handles.TdArray,handles.EULERINT,handles.ASCCT,T,ts] = ...
                    Boskovic_simulation(handles.Td,handles.gamma,handles.delta,...
                    handles.k0,handles.n,handles.t,handles.Umax,handles.I,qd_Array,...
                    wd_Array,ui,x);
                    handles.c1=2;
                    %handles.graphic1_popupmenu.String = 'K';
                    otherwise
                    disp('other value')
                end
            
            
                %Plots
                axes(handles.graphic1_axes);
                plot(handles.t(1:handles.n-1),180/pi*handles.angArray)
                grid on; xlabel('Time(s)');
                ylabel('Euler Angles(deg)');
                legend('Roll','Pitch','Yaw');
            
                axes(handles.graphic2_axes);
                plot(handles.t(1:handles.n-1),handles.xArray(5:7,:))
                grid on; xlabel('Time(s)');
                legend('X','Y','Z');
                ylabel('Angular Rates Error(rad/s)');
            
                %Enable buttons
                set(handles.graphic1_button,'Enable','on');
                set(handles.graphic2_button,'Enable','on');
            
                %Set push buttons
                set(handles.graphic1_popupmenu, 'value', 1);
                set(handles.graphic2_popupmenu, 'value', 3);
            
                %LS2125204: Update Controller performance parameters
                set(handles.settime_text, 'String', num2str(ts,'%.3f'));
                set(handles.eulerint_text, 'String', num2str(handles.EULERINT(end),'%.3f'));
                set(handles.ascct_text, 'String', num2str(handles.ASCCT(end),'%.3f'));
                set(handles.compCost_text, 'String', num2str(mean(T)));
            end
            %Update handles structure
            guidata(hObject, handles)
        end

        % Callback function
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ADCS simulator';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'CubeSats Control Attitude Evaluation';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Graph 1';
            app.uipanel1.Tag = 'uipanel1';
            app.graphic1_axes = uiaxes(app.uipanel1);
            app.graphic1_axes.Tag = 'graphic1_axes';
            app.graphic1_popupmenu = uidropdown(app.uipanel1);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'K'};
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.graphic1_button = uibutton(app.uipanel1, 'push');
            app.graphic1_button.ButtonPushedFcn = createCallbackFcn(app, @graphic1_button_Callback, true);
            app.graphic1_button.Tag = 'graphic1_button';
            app.graphic1_button.Enable = 'off';
            app.graphic1_button.Text = 'Graph';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Graph 2';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.graphic2_axes = uiaxes(app.uibuttongroup1);
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_button = uibutton(app.uibuttongroup1, 'push');
            app.graphic2_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_button_Callback, true);
            app.graphic2_button.Tag = 'graphic2_button';
            app.graphic2_button.Enable = 'off';
            app.graphic2_button.Text = 'Graph';
            app.text3 = uilabel(app.uibuttongroup1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Parameter:';
            app.graphic2_popupmenu = uidropdown(app.uibuttongroup1);
            app.graphic2_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'K'};
            app.graphic2_popupmenu.Tag = 'graphic2_popupmenu';
            app.graphic2_popupmenu.Value = 'Euler Angles (ZYX)';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Simulation Parameters';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.initTime_edit = uieditfield(app.uibuttongroup2, 'text');
            app.initTime_edit.Tag = 'initTime_edit';
            app.initTime_edit.Value = '0.0';
            app.finalTime_edit = uieditfield(app.uibuttongroup2, 'text');
            app.finalTime_edit.Tag = 'finalTime_edit';
            app.finalTime_edit.Value = '10.0';
            app.step_edit = uieditfield(app.uibuttongroup2, 'text');
            app.step_edit.Tag = 'step_edit';
            app.step_edit.Value = '1E-3';
            app.text4 = uilabel(app.uibuttongroup2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Initial Time:';
            app.text5 = uilabel(app.uibuttongroup2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Final Time:';
            app.text6 = uilabel(app.uibuttongroup2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Step:';
            app.text17 = uilabel(app.uibuttongroup2);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text18 = uilabel(app.uibuttongroup2);
            app.text18.Tag = 'text18';
            app.text18.Text = 's';
            app.text19 = uilabel(app.uibuttongroup2);
            app.text19.Tag = 'text19';
            app.text19.Text = 's';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'Model Setings';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.cubesat_button = uibutton(app.uibuttongroup5, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_button_Callback, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.perturbation_button = uibutton(app.uibuttongroup5, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_button_Callback, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.uibuttongroup6 = uibuttongroup(app.figure1);
            app.uibuttongroup6.Title = 'Controller';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.controller_popupmenu = uidropdown(app.uibuttongroup6);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.uibuttongroup6, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_button_Callback, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.uibuttongroup7 = uibuttongroup(app.figure1);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.text8 = uilabel(app.uibuttongroup7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.uibuttongroup7);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.uibuttongroup7);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.uibuttongroup7);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.uibuttongroup7);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.uibuttongroup7);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.uibuttongroup7);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.uibuttongroup7);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Convenio/Contrato N° PE501079885-2022-PROCIENCIA';
            app.run_button = uibutton(app.figure1, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_button_Callback, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run';
            app.logo = uiimage(app.figure1);
            app.logo.ImageClickedFcn = createCallbackFcn(app, @controller_button_Callback, true);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adcs.jpg');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LS2125204_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)LS2125204_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end