classdef boskovicSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        uipanel1          matlab.ui.container.Panel
        yaw_edit          matlab.ui.control.NumericEditField
        pitch_edit        matlab.ui.control.NumericEditField
        roll_edit         matlab.ui.control.NumericEditField
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        wz_edit           matlab.ui.control.EditField
        wy_edit           matlab.ui.control.EditField
        wx_edit           matlab.ui.control.EditField
        Apply_pushbutton  matlab.ui.control.Button
        uipanel2          matlab.ui.container.Panel
        k0_edit           matlab.ui.control.NumericEditField
        umax_edit         matlab.ui.control.NumericEditField
        gamma_edit        matlab.ui.control.NumericEditField
        delta_edit        matlab.ui.control.NumericEditField
        text11            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        text9             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        text2             matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app,data, data1)
            %%%Update setpoint quaternions
            euler = quat2eul(data.qd')*180/pi;
            app.roll_edit.Value  = euler(1);
            app.pitch_edit.Value = euler(2);
            app.yaw_edit.Value   = euler(3);
            %%%Update setpoint angular rate
            %app.wx_edit.Value  = data.Wd(1);
            %app.wy_edit.Value  = data.Wd(2);
            %app.wz_edit.Value  = data.Wd(3);

            %%%Update controller parameters
            app.k0_edit.Value    = data1.k0;
            app.umax_edit.Value  = data1.Umax;
            app.gamma_edit.Value = data1.gamma;
            app.delta_edit.Value = data1.delta;
        end    
        
        function newData = updateParameters(app)
            %%%Update euler angles
            q = eul2quat(pi/180*[app.roll_edit.Value,...
                 app.pitch_edit.Value,app.yaw_edit.Value])';
            %%%Update quaternions
            newData.qd(1) = q(1);
            newData.qd(2) = q(2);
            newData.qd(3) = q(3);
            newData.qd(4) = q(4);
            %%%Update angular rates
            newData.k0    = app.k0_edit.Value;
            newData.Umax  = app.umax_edit.Value;
            newData.gamma = app.gamma_edit.Value;
            newData.delta = app.delta_edit.Value;
            newData.i = 5;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData, mainAppData1)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData,mainAppData1);
        end

        % Button pushed function: Apply_pushbutton
        function Apply_pushbuttonButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp,dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Boskovic''s Controller Settings';
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Gains';
            app.uipanel2.Tag = 'uipanel2';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Delta:';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Gamma:';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'K0:';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Umax:';
            app.delta_edit = uieditfield(app.uipanel2, 'numeric');
            app.delta_edit.ValueDisplayFormat = '%.2E';
            app.delta_edit.Value = 0.01;
            app.gamma_edit = uieditfield(app.uipanel2, 'numeric');
            app.gamma_edit.ValueDisplayFormat = '%.2E';
            app.gamma_edit.Value = 0.001;
            app.umax_edit = uieditfield(app.uipanel2, 'numeric');
            app.umax_edit.ValueDisplayFormat = '%.2E';
            app.umax_edit.Value = 0.1;
            app.k0_edit = uieditfield(app.uipanel2, 'numeric');
            app.k0_edit.ValueDisplayFormat = '%.2E';
            app.k0_edit.Value = 1;
            app.Apply_pushbutton = uibutton(app.UIFigure, 'push');
            app.Apply_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @Apply_pushbuttonButtonPushed, true);
            app.Apply_pushbutton.Tag = 'Apply_pushbutton';
            app.Apply_pushbutton.Text = 'Apply';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Set Point';
            app.uipanel1.Tag = 'uipanel1';
            app.wx_edit = uieditfield(app.uipanel1, 'text');
            app.wx_edit.Tag = 'wx_edit';
            app.wx_edit.Enable = 'off';
            app.wx_edit.Value = '0.00';
            app.wy_edit = uieditfield(app.uipanel1, 'text');
            app.wy_edit.Tag = 'wy_edit';
            app.wy_edit.Enable = 'off';
            app.wy_edit.Value = '0.00';
            app.wz_edit = uieditfield(app.uipanel1, 'text');
            app.wz_edit.Tag = 'wz_edit';
            app.wz_edit.Enable = 'off';
            app.wz_edit.Value = '0.00';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Y';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Z';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Euler Angles (deg):';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Angular Rate (deg/s):';
            app.roll_edit = uieditfield(app.uipanel1, 'numeric');
            app.roll_edit.ValueDisplayFormat = '%.3f';
            app.roll_edit.Value = 1;
            app.pitch_edit = uieditfield(app.uipanel1, 'numeric');
            app.pitch_edit.ValueDisplayFormat = '%.3f';
            app.pitch_edit.Value = 1;
            app.yaw_edit = uieditfield(app.uipanel1, 'numeric');
            app.yaw_edit.ValueDisplayFormat = '%.3f';
            app.yaw_edit.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = boskovicSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end