classdef feedbackSettingd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        Applu_pushbutton  matlab.ui.control.Button
        uipanel2          matlab.ui.container.Panel
        P_edit            matlab.ui.control.NumericEditField
        K_edit            matlab.ui.control.NumericEditField
        text13            matlab.ui.control.Label
        text12            matlab.ui.control.Label
        text11            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        P                 matlab.ui.control.Label
        K                 matlab.ui.control.Label
        uipanel1          matlab.ui.container.Panel
        yaw_edit          matlab.ui.control.NumericEditField
        pitch_edit        matlab.ui.control.NumericEditField
        roll_edit         matlab.ui.control.NumericEditField
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        wz_edit           matlab.ui.control.EditField
        wy_edit           matlab.ui.control.EditField
        wx_edit           matlab.ui.control.EditField
        text2             matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        function updateInterface(app,data, data1)
            %%%Update setpoint quaternions
            euler = quat2eul(data.qd')*180/pi;
            app.roll_edit.Value  = euler(1);
            app.pitch_edit.Value = euler(2);
            app.yaw_edit.Value   = euler(3);
            %%%Update setpoint angular rate
            %app.wx_edit.Value  = data.Wd(1);
            %app.wy_edit.Value  = data.Wd(2);
            %app.wz_edit.Value  = data.Wd(3);

            %%%Update controller parameters
            app.K_edit.Value = data1.Keye(1,1);
            app.P_edit.Value = data1.Peye(1,1);
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData, mainAppData2)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData, mainAppData2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'FeedBack Controller Settings';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Set Point';
            app.uipanel1.Tag = 'uipanel1';
            app.wx_edit = uieditfield(app.uipanel1, 'text');
            app.wx_edit.Tag = 'wx_edit';
            app.wx_edit.Enable = 'off';
            app.wx_edit.Value = '0.00';
            app.wy_edit = uieditfield(app.uipanel1, 'text');
            app.wy_edit.Tag = 'wy_edit';
            app.wy_edit.Enable = 'off';
            app.wy_edit.Value = '0.00';
            app.wz_edit = uieditfield(app.uipanel1, 'text');
            app.wz_edit.Tag = 'wz_edit';
            app.wz_edit.Enable = 'off';
            app.wz_edit.Value = '0.00';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Y';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Z';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Euler Angles (deg):';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Angular Rate (deg/s):';
            app.roll_edit = uieditfield(app.uipanel1, 'numeric');
            app.roll_edit.ValueDisplayFormat = '%.2f';
            app.roll_edit.Value = 1;
            app.pitch_edit = uieditfield(app.uipanel1, 'numeric');
            app.pitch_edit.ValueDisplayFormat = '%.2f';
            app.pitch_edit.Value = 1;
            app.yaw_edit = uieditfield(app.uipanel1, 'numeric');
            app.yaw_edit.ValueDisplayFormat = '%.2f';
            app.yaw_edit.Value = 1;
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Gains';
            app.uipanel2.Tag = 'uipanel2';
            app.K = uilabel(app.uipanel2);
            app.K.Tag = 'K';
            app.K.Text = 'K:';
            app.P = uilabel(app.uipanel2);
            app.P.Tag = 'P';
            app.P.Text = 'P:';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'DIAG(';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'DIAG(';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = ')';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = ')';
            app.K_edit = uieditfield(app.uipanel2, 'numeric');
            app.K_edit.ValueDisplayFormat = '%.2f';
            app.K_edit.Value = 1;
            app.P_edit = uieditfield(app.uipanel2, 'numeric');
            app.P_edit.ValueDisplayFormat = '%.2f';
            app.P_edit.Value = 1;
            app.Applu_pushbutton = uibutton(app.UIFigure, 'push');
            app.Applu_pushbutton.Tag = 'Applu_pushbutton';
            app.Applu_pushbutton.Text = 'Apply';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = feedbackSettingd(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end