classdef perturbationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        constPer_pushbutton         matlab.ui.control.Button
        EnableButtonsPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        MiscellaneousPerturbationSwitch  matlab.ui.control.Switch
        MiscellaneousPerturbationSwitchLabel  matlab.ui.control.Label
        ConstantPerturbationSwitch  matlab.ui.control.Switch
        ConstantPerturbationSwitchLabel  matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        text7                       matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        w3_edit                     matlab.ui.control.NumericEditField
        w2_edit                     matlab.ui.control.NumericEditField
        w1_edit                     matlab.ui.control.NumericEditField
        A_edit                      matlab.ui.control.NumericEditField
        uipanel1                    matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        z_text                      matlab.ui.control.Label
        y_text                      matlab.ui.control.Label
        x_text                      matlab.ui.control.Label
        Tgz_edit                    matlab.ui.control.NumericEditField
        Tgy_edit                    matlab.ui.control.NumericEditField
        Tgx_edit                    matlab.ui.control.NumericEditField
        perturbation_text           matlab.ui.control.Label
        text2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app,data)
            %%%Update constant disturbances
            app.Tgx_edit.Value = data.constant.Tdx;
            app.Tgy_edit.Value = data.constant.Tdy;
            app.Tgz_edit.Value = data.constant.Tdz;
            %%%Update variable disturbances
            app.A_edit.Value  = data.variable.A ;
            app.w1_edit.Value = data.variable.w1;
            app.w2_edit.Value = data.variable.w2;
            app.w3_edit.Value = data.variable.w3;
            %%%Update enable values
            app.ConstantPerturbationSwitch.ValueIndex      = data.constant.enable;
            app.MiscellaneousPerturbationSwitch.ValueIndex = data.variable.enable;
        end        
        
        function newData = updateParameters(app)
            %%%Update constant disturbances
            newData.Tdx = app.Tgx_edit.Value;
            newData.Tdy = app.Tgy_edit.Value;
            newData.Tdz = app.Tgz_edit.Value;
            %%%Update miscellaneous disturbances
            newData.A  = app.A_edit.Value;
            newData.w1 = app.w1_edit.Value;
            newData.w2 = app.w2_edit.Value; 
            newData.w3 = app.w3_edit.Value; 
            %%%Read enable button
            newData.constantEnable = app.ConstantPerturbationSwitch.ValueIndex;
            newData.miscEnable     = app.MiscellaneousPerturbationSwitch.ValueIndex;
            newData.i = 2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Button pushed function: constPer_pushbutton
        function constPer_pushbuttonButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end

        % Callback function
        function miscPer_pushbuttonButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Perturbation Settings';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Constant Perturbation';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.perturbation_text = uilabel(app.GridLayout);
            app.perturbation_text.Tag = 'text6';
            app.perturbation_text.Text = 'Perturbation (Nm):';
            app.Tgx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgx_edit.ValueDisplayFormat = '%.2E';
            app.Tgy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgy_edit.ValueDisplayFormat = '%.2E';
            app.Tgz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgz_edit.ValueDisplayFormat = '%.2E';
            app.x_text = uilabel(app.GridLayout);
            app.x_text.Tag = 'text6';
            app.x_text.Text = 'x';
            app.y_text = uilabel(app.GridLayout);
            app.y_text.Tag = 'text6';
            app.y_text.Text = 'y';
            app.z_text = uilabel(app.GridLayout);
            app.z_text.Tag = 'text6';
            app.z_text.Text = 'z';
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Miscellaneous Perturbation';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.A_edit = uieditfield(app.GridLayout2, 'numeric');
            app.A_edit.ValueDisplayFormat = '%.E';
            app.w1_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w1_edit.ValueDisplayFormat = '%.E';
            app.w2_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w2_edit.ValueDisplayFormat = '%.E';
            app.w3_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w3_edit.ValueDisplayFormat = '%.E';
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = '[ sin(';
            app.text10 = uilabel(app.GridLayout2);
            app.text10.Tag = 'text10';
            app.text10.Text = 't), sin(';
            app.text12 = uilabel(app.GridLayout2);
            app.text12.Tag = 'text12';
            app.text12.Text = 't), sin(';
            app.text13 = uilabel(app.GridLayout2);
            app.text13.Tag = 'text13';
            app.text13.Text = 't)] ''';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Perturbation (Nm):';
            app.EnableButtonsPanel = uipanel(app.UIFigure);
            app.EnableButtonsPanel.Title = 'Enable Buttons';
            app.GridLayout3 = uigridlayout(app.EnableButtonsPanel);
            app.ConstantPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.ConstantPerturbationSwitchLabel.Text = 'Constant Perturbation';
            app.ConstantPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.MiscellaneousPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.MiscellaneousPerturbationSwitchLabel.Text = 'Miscellaneous Perturbation';
            app.MiscellaneousPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.constPer_pushbutton = uibutton(app.UIFigure, 'push');
            app.constPer_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @constPer_pushbuttonButtonPushed, true);
            app.constPer_pushbutton.Tag = 'constPer_pushbutton';
            app.constPer_pushbutton.Text = 'Apply';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perturbationSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end